classdef mibDeepGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        BottomButtonsPanel              matlab.ui.container.Panel
        PredictButton                   matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        NetworkPanel                    matlab.ui.container.Panel
        Architecture                    matlab.ui.control.DropDown
        GPUDropDown                     matlab.ui.control.DropDown
        gpuInfoBtn                      matlab.ui.control.Button
        SelectNetwork                   matlab.ui.control.Button
        NetworkFilename                 matlab.ui.control.EditField
        GPULabel_2                      matlab.ui.control.Label
        ArchitectureLabel               matlab.ui.control.Label
        NetworkfilenameEditFieldLabel   matlab.ui.control.Label
        Mode                            matlab.ui.container.TabGroup
        Preprocess                      matlab.ui.container.Tab
        RandomGeneratorSeed             matlab.ui.control.NumericEditField
        ValidationFraction              matlab.ui.control.NumericEditField
        ResultingImagesDir              matlab.ui.control.EditField
        OriginalPredictionImagesDir     matlab.ui.control.EditField
        OriginalTrainingImagesDir       matlab.ui.control.EditField
        ImageFilenameExtensionTraining  matlab.ui.control.DropDown
        BioformatsTraining              matlab.ui.control.CheckBox
        BioformatsTrainingIndex         matlab.ui.control.NumericEditField
        SelectOriginalTrainingImagesDir  matlab.ui.control.Button
        ImageFilenameExtension          matlab.ui.control.DropDown
        Bioformats                      matlab.ui.control.CheckBox
        BioformatsIndex                 matlab.ui.control.NumericEditField
        SelectOriginalPredictionImagesDir  matlab.ui.control.Button
        SingleModelTrainingFile         matlab.ui.control.CheckBox
        MaskAway                        matlab.ui.control.CheckBox
        ModelFilenameExtension          matlab.ui.control.DropDown
        MaskFilenameExtension           matlab.ui.control.DropDown
        NumberOfClassesPreprocessing    matlab.ui.control.NumericEditField
        SelectResultingImagesDir        matlab.ui.control.Button
        CompressProcessedImages         matlab.ui.control.CheckBox
        CompressProcessedModels         matlab.ui.control.CheckBox
        UseParallelComputing            matlab.ui.control.CheckBox
        PreprocessingParForWorkers      matlab.ui.control.NumericEditField
        PreprocessingMode               matlab.ui.control.DropDown
        IndexEditField_2Label           matlab.ui.control.Label
        IndexEditFieldLabel             matlab.ui.control.Label
        ModelextensionDropDownLabel_2   matlab.ui.control.Label
        NumberofclassesEditFieldLabel_2  matlab.ui.control.Label
        ModelextensionDropDownLabel     matlab.ui.control.Label
        WorkersEditFieldLabel           matlab.ui.control.Label
        RandomgeneratorseedLabel        matlab.ui.control.Label
        extensionLabel                  matlab.ui.control.Label
        DirectorywithimagesandmodelsfortrainingLabel  matlab.ui.control.Label
        DirectorywithresultingimagesLabel  matlab.ui.control.Label
        extensionLabel_2                matlab.ui.control.Label
        DirectorywithimagesforpredictionLabel  matlab.ui.control.Label
        PreprocessforDropDownLabel      matlab.ui.control.Label
        FractionofimagesforvalidationEditFieldLabel  matlab.ui.control.Label
        Train                           matlab.ui.container.Tab
        T_ExportTrainingPlots           matlab.ui.control.CheckBox
        T_SaveProgress                  matlab.ui.control.CheckBox
        T_RandomGeneratorSeed           matlab.ui.control.NumericEditField
        T_SegmentationLayer             matlab.ui.control.DropDown
        T_ActivationLayer               matlab.ui.control.DropDown
        T_FilterSize                    matlab.ui.control.NumericEditField
        T_NumFirstEncoderFilters        matlab.ui.control.NumericEditField
        T_MiniBatchSize                 matlab.ui.control.NumericEditField
        T_PatchesPerImage               matlab.ui.control.NumericEditField
        T_EncoderDepth                  matlab.ui.control.NumericEditField
        T_NumberOfClasses               matlab.ui.control.NumericEditField
        T_ConvolutionPadding            matlab.ui.control.DropDown
        T_InputPatchSize                matlab.ui.control.EditField
        T_SegmentationLayerSettings     matlab.ui.control.Button
        T_ActivationLayerSettings       matlab.ui.control.Button
        ActivationlayerLabel            matlab.ui.control.Label
        T_RandomgeneratorseedLabel      matlab.ui.control.Label
        TransferLearning                matlab.ui.control.Button
        InputLayerSettings              matlab.ui.control.Button
        PaddingLabel                    matlab.ui.control.Label
        AugmentationPanel               matlab.ui.container.Panel
        T_augmentation                  matlab.ui.control.CheckBox
        T_AugmentationPreviewSettings   matlab.ui.control.Button
        T_AugmentationPreview           matlab.ui.control.Button
        Augmentation3DSettings          matlab.ui.control.Button
        Augmentation2DSettings          matlab.ui.control.Button
        FiltersizeLabel                 matlab.ui.control.Label
        FiltersLabel                    matlab.ui.control.Label
        MiniBatchSizeLabel              matlab.ui.control.Label
        PatchesperimageLabel            matlab.ui.control.Label
        TrainSettings                   matlab.ui.control.Button
        EncoderdepthLabel               matlab.ui.control.Label
        CheckNetwork                    matlab.ui.control.Button
        SegmentationlayerDropDownLabel  matlab.ui.control.Label
        NumberofclassesEditFieldLabel   matlab.ui.control.Label
        InputpatchsizeLabel             matlab.ui.control.Label
        Predict                         matlab.ui.container.Tab
        P_ModelFiles                    matlab.ui.control.DropDown
        ModelfilesLabel                 matlab.ui.control.Label
        P_ScoreFiles                    matlab.ui.control.DropDown
        ScorefilesDropDownLabel         matlab.ui.control.Label
        P_OverlappingTiles              matlab.ui.control.CheckBox
        P_MiniBatchSize                 matlab.ui.control.NumericEditField
        MinibatchsizeforpredictionnotusedintheoverlappingmodeLabel  matlab.ui.control.Label
        ExploreActivations              matlab.ui.control.Button
        PreviewresultsPanel             matlab.ui.container.Panel
        bigImageMode                    matlab.ui.control.CheckBox
        EvaluateSegmentation            matlab.ui.control.Button
        PreviewResultsText2             matlab.ui.control.Label
        PreviewResultsText              matlab.ui.control.Label
        LoadPredictions                 matlab.ui.control.Button
        LoadModels                      matlab.ui.control.Button
        Options                         matlab.ui.container.Tab
        OptionsToolsPanel               matlab.ui.container.Panel
        ImportNetworkButton             matlab.ui.control.Button
        OptionsCountLabelsButton        matlab.ui.control.Button
        ExportNetworkToONNXButton       matlab.ui.control.Button
        Disable2DaugmentationsButton    matlab.ui.control.Button
        Reset2DaugmentationButton       matlab.ui.control.Button
        CustomtrainingplotPanel         matlab.ui.container.Panel
        O_FractionOfPreviewPatches      matlab.ui.control.NumericEditField
        O_NumberOfPoints                matlab.ui.control.NumericEditField
        O_RefreshRateIter               matlab.ui.control.NumericEditField
        FractionofimagesforpreviewLabel  matlab.ui.control.Label
        O_PreviewImagePatches           matlab.ui.control.CheckBox
        NumberofpointsLabel             matlab.ui.control.Label
        RefreshrateiterLabel            matlab.ui.control.Label
        O_CustomTrainingProgressWindow  matlab.ui.control.CheckBox
        ConfigfilesPanel                matlab.ui.container.Panel
        ConfigFilesLabel                matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        winController % controller of the view % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = 'off';
            app.ConfigFilesLabel.Text = sprintf('Config files are automatically\ncreated before training and\ninclude all DeepMIB settings\nbut without the trained network');
            app.PreviewResultsText.Text = sprintf('Use these buttons to preview predictions \nusing the currently active container of MIB.\nFor 3D networks only the first dataset will be shown');
            app.PreviewResultsText2.Text = sprintf('Press to evaluate results of the segmentation; requires\nground truth *.model files next to datasets for prediction');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            global mibPath;
            web(fullfile(mibPath, 'techdoc', 'html', 'ug_gui_menu_tools_deeplearning.html'), '-helpbrowser');
        end

        % Callback function: NetworkFilename, SelectNetwork
        function SelectNetworkButtonPushed(app, event)
            if strcmp(event.Source.Tag, 'NetworkFilename')
                app.winController.selectNetwork(event.Source.Value);   % callback from editbox change 
            else
                app.winController.selectNetwork();    
            end

        end

        % Value changed function: BioformatsIndex, 
        % BioformatsTrainingIndex, CompressProcessedImages, 
        % CompressProcessedModels, ImageFilenameExtension, 
        % ImageFilenameExtensionTraining, MaskAway, MaskFilenameExtension, 
        % ModelFilenameExtension, O_FractionOfPreviewPatches, 
        % O_NumberOfPoints, O_RefreshRateIter, P_MiniBatchSize, 
        % P_ModelFiles, P_OverlappingTiles, P_ScoreFiles, 
        % RandomGeneratorSeed, T_ConvolutionPadding, T_EncoderDepth, 
        % T_ExportTrainingPlots, T_FilterSize, T_MiniBatchSize, 
        % T_NumFirstEncoderFilters, T_PatchesPerImage, 
        % T_RandomGeneratorSeed, T_SaveProgress, ValidationFraction
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: SelectOriginalPredictionImagesDir, 
        % SelectOriginalTrainingImagesDir, SelectResultingImagesDir
        function SelectDirerctories(app, event)
            app.winController.SelectDirerctories(event);
        end

        % Value changed function: OriginalPredictionImagesDir, 
        % OriginalTrainingImagesDir, ResultingImagesDir
        function ImageDirectoriesValueChanged(app, event)
            app.winController.ImageDirectoriesValueChanged(event);
            
        end

        % Button pushed function: PredictButton, PreprocessButton, 
        % TrainButton
        function StartButtonPushed(app, event)
            app.winController.Start(event);
        end

        % Button pushed function: CheckNetwork
        function CheckNetworkButtonPushed(app, event)
            app.winController.CheckNetwork();
        end

        % Button pushed function: TrainSettings
        function TrainSettingsButtonPushed(app, event)
            app.winController.setTrainingSettings();
        end

        % Selection change function: Mode
        function ModeSelectionChanged(app, event)
            selectedTab = app.Mode.SelectedTab;
            app.SelectNetwork.Enable = 'off';
            app.TrainButton.Enable = 'off';
            app.PredictButton.Enable = 'off';
            app.PreprocessButton.Enable = 'off';
            app.PreprocessButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.TrainButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.PredictButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.SelectNetwork.BackgroundColor = app.NetworkPanel.BackgroundColor;
            switch selectedTab.Tag
                case 'Preprocess'
                    app.PreprocessButton.Enable = 'on';
                    app.PreprocessButton.BackgroundColor = app.Preprocess.BackgroundColor;
                    app.NetworkFilename.Enable = 'off';
                case 'Train'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
                case 'Predict'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: Augmentation2DSettings, 
        % Augmentation3DSettings
        function AugmentationSettingsButtonPushed(app, event)
            switch event.Source.Tag
                case 'Augmentation2DSettings'
                    app.winController.setAugmentation2DSettings();
                case 'Augmentation3DSettings'
                    app.winController.setAugmentation3DSettings();
            end
        end

        % Value changed function: T_augmentation
        function T_augmentationValueChanged(app, event)
            app.winController.T_augmentationCallback();
        end

        % Value changed function: T_InputPatchSize
        function T_InputPatchSizeValueChanged(app, event)
            value = app.T_InputPatchSize.Value;
            value = str2num(value); %#ok<ST2NM> 
            if numel(value) ~= 4
                errordlg(sprintf('!!! Error !!!\n\nPlease provide the input patch size as 4 numbers that define\nheight, width, depth, colors\n\nFor example:\n"32, 32, 1, 3" for 2D U-net of 3 color channel images\n"64, 64, 64, 1" for 3D U-net of 1 color channel images'), ...
                    'Wrong patch size');
                return;
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: InputLayerSettings
        function InputLayerSettingsButtonPushed(app, event)
            app.winController.setInputLayerSettings();    
        end

        % Button pushed function: LoadPredictions
        function LoadPredictionsButtonPushed(app, event)
            app.winController.previewPredictions(); 
        end

        % Button pushed function: LoadModels
        function LoadModelsButtonPushed(app, event)
            app.winController.previewModels(); 
        end

        % Button pushed function: EvaluateSegmentation
        function EvaluateSegmentationButtonPushed(app, event)
            app.winController.EvaluateSegmentation(); 
        end

        % Button pushed function: ExploreActivations
        function ExploreActivationsButtonPushed(app, event)
            app.winController.ExploreActivations();
        end

        % Value changed function: Bioformats, BioformatsTraining
        function BioformatsValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.BioformatsCallback(event);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.winController.loadConfig();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.winController.saveConfig();
        end

        % Value changed function: GPUDropDown
        function GPUDropDownValueChanged(app, event)
            app.winController.selectGPUDevice();
        end

        % Button pushed function: gpuInfoBtn
        function gpuInfoBtnButtonPushed(app, event)
            app.winController.gpuInfo();
        end

        % Button pushed function: TransferLearning
        function TransferLearningButtonPushed(app, event)
            app.winController.transferLearning();
        end

        % Value changed function: UseParallelComputing
        function UseParallelComputingValueChanged(app, event)
            if app.UseParallelComputing.Value
                app.PreprocessingParForWorkers.Enable = 'on';
            else
                app.PreprocessingParForWorkers.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: ExportNetworkToONNXButton
        function ExportNetworkToONNXButtonPushed(app, event)
            app.winController.ExportNetworkToONNXButton();
        end

        % Value changed function: SingleModelTrainingFile
        function SingleModelTrainingFileValueChanged(app, event)
            app.winController.SingleModelTrainingFileValueChanged(event);
        end

        % Value changed function: Architecture
        function ArchitectureValueChanged(app, event)
            app.winController.ArchitectureValueChanged(event);
        end

        % Value changed function: NumberOfClassesPreprocessing
        function NumberOfClassesPreprocessingValueChanged(app, event)
            app.T_NumberOfClasses.Value = app.NumberOfClassesPreprocessing.Value;
            event2.Source = app.T_NumberOfClasses;
            app.T_NumberOfClassesValueChanged(event2);
        end

        % Value changed function: T_NumberOfClasses
        function T_NumberOfClassesValueChanged(app, event)
            app.NumberOfClassesPreprocessing.Value = app.T_NumberOfClasses.Value;
            app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: T_ActivationLayer
        function T_ActivationLayerValueChanged(app, event)
            app.winController.T_ActivationLayerCallback();
        end

        % Value changed function: O_CustomTrainingProgressWindow
        function O_CustomTrainingProgressWindowValueChanged(app, event)
            if app.O_CustomTrainingProgressWindow.Value
                app.O_RefreshRateIter.Enable = 'on';
                app.O_NumberOfPoints.Enable = 'on';
                app.O_PreviewImagePatches.Enable = 'on';
                app.O_FractionOfPreviewPatches.Enable = 'on';
            else
                app.O_RefreshRateIter.Enable = 'off';
                app.O_NumberOfPoints.Enable = 'off';
                app.O_PreviewImagePatches.Enable = 'off';
                app.O_FractionOfPreviewPatches.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
            event2.Source = app.O_PreviewImagePatches;
            app.O_PreviewImagePatchesValueChanged(event2);
        end

        % Value changed function: O_PreviewImagePatches
        function O_PreviewImagePatchesValueChanged(app, event)
            if app.O_PreviewImagePatches.Value && strcmp(app.O_PreviewImagePatches.Enable, 'on')
                app.O_FractionOfPreviewPatches.Enable = 'on';
            else
                app.O_FractionOfPreviewPatches.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: Reset2DaugmentationButton
        function Reset2DaugmentationButtonPushed(app, event)
            app.winController.reset2Daugmentation();
        end

        % Button pushed function: T_AugmentationPreview
        function T_AugmentationPreviewButtonPushed(app, event)
            app.winController.previewAugmentations();
        end

        % Button pushed function: T_AugmentationPreviewSettings
        function T_AugmentationPreviewSettingsButtonPushed(app, event)
            app.winController.setPreviewAugmentationSettings();
        end

        % Value changed function: PreprocessingMode
        function PreprocessingModeValueChanged(app, event)
            app.winController.updatePreprocessingMode();
        end

        % Value changed function: T_SegmentationLayer
        function T_SegmentationLayerValueChanged(app, event)
            app.winController.T_SegmentationLayerCallback();
        end

        % Button pushed function: T_ActivationLayerSettings
        function T_ActivationLayerSettingsButtonPushed(app, event)
            app.winController.setActivationLayerOptions();
        end

        % Button pushed function: T_SegmentationLayerSettings
        function T_SegmentationLayerSettingsButtonPushed(app, event)
            app.winController.setSegmentationLayerOptions();
        end

        % Button pushed function: Disable2DaugmentationsButton
        function Disable2DaugmentationsButtonPushed(app, event)
            app.winController.disable2Daugmentation();
        end

        % Button pushed function: OptionsCountLabelsButton
        function OptionsCountLabelsButtonPushed(app, event)
            app.winController.countLabels()
        end

        % Button pushed function: ImportNetworkButton
        function ImportNetworkButtonPushed(app, event)
            app.winController.importNetwork()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'DeepMIB';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.Figure);
            app.Mode = uitabgroup(app.GridLayout);
            app.Mode.SelectionChangedFcn = createCallbackFcn(app, @ModeSelectionChanged, true);
            app.Preprocess = uitab(app.Mode);
            app.Preprocess.Title = 'Directories and Preprocessing';
            app.FractionofimagesforvalidationEditFieldLabel = uilabel(app.Preprocess);
            app.FractionofimagesforvalidationEditFieldLabel.Text = 'Fraction of images for validation';
            app.PreprocessforDropDownLabel = uilabel(app.Preprocess);
            app.PreprocessforDropDownLabel.Text = 'Preprocess for';
            app.DirectorywithimagesforpredictionLabel = uilabel(app.Preprocess);
            app.DirectorywithimagesforpredictionLabel.Text = 'Directory with images for prediction';
            app.extensionLabel_2 = uilabel(app.Preprocess);
            app.extensionLabel_2.Text = 'extension';
            app.DirectorywithresultingimagesLabel = uilabel(app.Preprocess);
            app.DirectorywithresultingimagesLabel.Text = 'Directory with resulting images';
            app.DirectorywithimagesandmodelsfortrainingLabel = uilabel(app.Preprocess);
            app.DirectorywithimagesandmodelsfortrainingLabel.Text = 'Directory with images and models for training';
            app.extensionLabel = uilabel(app.Preprocess);
            app.extensionLabel.Text = 'extension';
            app.RandomgeneratorseedLabel = uilabel(app.Preprocess);
            app.RandomgeneratorseedLabel.Text = 'Random generator seed';
            app.WorkersEditFieldLabel = uilabel(app.Preprocess);
            app.WorkersEditFieldLabel.Text = 'Workers';
            app.ModelextensionDropDownLabel = uilabel(app.Preprocess);
            app.ModelextensionDropDownLabel.Text = 'Model extension';
            app.NumberofclassesEditFieldLabel_2 = uilabel(app.Preprocess);
            app.NumberofclassesEditFieldLabel_2.Text = 'Number of classes';
            app.ModelextensionDropDownLabel_2 = uilabel(app.Preprocess);
            app.ModelextensionDropDownLabel_2.Text = 'Mask extension';
            app.IndexEditFieldLabel = uilabel(app.Preprocess);
            app.IndexEditFieldLabel.Text = 'Index';
            app.IndexEditField_2Label = uilabel(app.Preprocess);
            app.IndexEditField_2Label.Text = 'Index';
            app.PreprocessingMode = uidropdown(app.Preprocess);
            app.PreprocessingMode.ValueChangedFcn = createCallbackFcn(app, @PreprocessingModeValueChanged, true);
            app.PreprocessingParForWorkers = uieditfield(app.Preprocess, 'numeric');
            app.PreprocessingParForWorkers.Limits = [1 Inf];
            app.PreprocessingParForWorkers.Tooltip = {'define number of workers for parallel processsing'};
            app.PreprocessingParForWorkers.Value = 1;
            app.UseParallelComputing = uicheckbox(app.Preprocess);
            app.UseParallelComputing.ValueChangedFcn = createCallbackFcn(app, @UseParallelComputingValueChanged, true);
            app.UseParallelComputing.Tooltip = {'enable parallel computing during image preprocessing'};
            app.UseParallelComputing.Text = 'Use parallel processing';
            app.CompressProcessedModels = uicheckbox(app.Preprocess);
            app.CompressProcessedModels.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedModels.Text = 'Compress processed models';
            app.CompressProcessedImages = uicheckbox(app.Preprocess);
            app.CompressProcessedImages.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedImages.Text = 'Compress processed images';
            app.SelectResultingImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectResultingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectResultingImagesDir.Tooltip = {'Select the output directory for results'};
            app.SelectResultingImagesDir.Text = '...';
            app.NumberOfClassesPreprocessing = uieditfield(app.Preprocess, 'numeric');
            app.NumberOfClassesPreprocessing.Limits = [1 255];
            app.NumberOfClassesPreprocessing.RoundFractionalValues = 'on';
            app.NumberOfClassesPreprocessing.ValueChangedFcn = createCallbackFcn(app, @NumberOfClassesPreprocessingValueChanged, true);
            app.NumberOfClassesPreprocessing.Enable = 'off';
            app.NumberOfClassesPreprocessing.Tooltip = {'Number of classes including exterior'};
            app.NumberOfClassesPreprocessing.Value = 1;
            app.MaskFilenameExtension = uidropdown(app.Preprocess);
            app.MaskFilenameExtension.Items = {'MASK', 'AM', 'PNG', 'TIF'};
            app.MaskFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaskFilenameExtension.Enable = 'off';
            app.MaskFilenameExtension.Value = 'MASK';
            app.ModelFilenameExtension = uidropdown(app.Preprocess);
            app.ModelFilenameExtension.Items = {'MODEL', 'AM', 'PNG', 'TIF'};
            app.ModelFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ModelFilenameExtension.Enable = 'off';
            app.ModelFilenameExtension.Value = 'MODEL';
            app.MaskAway = uicheckbox(app.Preprocess);
            app.MaskAway.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaskAway.Text = 'Use masking';
            app.SingleModelTrainingFile = uicheckbox(app.Preprocess);
            app.SingleModelTrainingFile.ValueChangedFcn = createCallbackFcn(app, @SingleModelTrainingFileValueChanged, true);
            app.SingleModelTrainingFile.Text = 'Single MIB model file';
            app.SingleModelTrainingFile.Value = true;
            app.SelectOriginalPredictionImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectOriginalPredictionImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalPredictionImagesDir.Tooltip = {'Select directory with original images to be used for prediction'};
            app.SelectOriginalPredictionImagesDir.Text = '...';
            app.BioformatsIndex = uieditfield(app.Preprocess, 'numeric');
            app.BioformatsIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Bioformats = uicheckbox(app.Preprocess);
            app.Bioformats.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.Bioformats.Text = 'Bio';
            app.ImageFilenameExtension = uidropdown(app.Preprocess);
            app.ImageFilenameExtension.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtension.Value = 'TIF';
            app.SelectOriginalTrainingImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectOriginalTrainingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalTrainingImagesDir.Tooltip = {'Select directory with original images and models. The images and models should be placed under "Images" and "Labels" subfolders correspondingly'};
            app.SelectOriginalTrainingImagesDir.Text = '...';
            app.BioformatsTrainingIndex = uieditfield(app.Preprocess, 'numeric');
            app.BioformatsTrainingIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.BioformatsTraining = uicheckbox(app.Preprocess);
            app.BioformatsTraining.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.BioformatsTraining.Text = 'Bio';
            app.ImageFilenameExtensionTraining = uidropdown(app.Preprocess);
            app.ImageFilenameExtensionTraining.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtensionTraining.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtensionTraining.Value = 'TIF';
            app.OriginalTrainingImagesDir = uieditfield(app.Preprocess, 'text');
            app.OriginalTrainingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.OriginalPredictionImagesDir = uieditfield(app.Preprocess, 'text');
            app.OriginalPredictionImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.ResultingImagesDir = uieditfield(app.Preprocess, 'text');
            app.ResultingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.ValidationFraction = uieditfield(app.Preprocess, 'numeric');
            app.ValidationFraction.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ValidationFraction.Value = 0.25;
            app.RandomGeneratorSeed = uieditfield(app.Preprocess, 'numeric');
            app.RandomGeneratorSeed.Limits = [0 Inf];
            app.RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.RandomGeneratorSeed.Value = 2;
            app.Train = uitab(app.Mode);
            app.Train.Title = 'Train';
            app.InputpatchsizeLabel = uilabel(app.Train);
            app.InputpatchsizeLabel.Text = 'Input patch size';
            app.NumberofclassesEditFieldLabel = uilabel(app.Train);
            app.NumberofclassesEditFieldLabel.Text = 'Number of classes';
            app.SegmentationlayerDropDownLabel = uilabel(app.Train);
            app.SegmentationlayerDropDownLabel.Text = 'Segmentation layer';
            app.CheckNetwork = uibutton(app.Train, 'push');
            app.CheckNetwork.ButtonPushedFcn = createCallbackFcn(app, @CheckNetworkButtonPushed, true);
            app.CheckNetwork.Text = 'Check network';
            app.EncoderdepthLabel = uilabel(app.Train);
            app.EncoderdepthLabel.Text = 'Encoder depth';
            app.TrainSettings = uibutton(app.Train, 'push');
            app.TrainSettings.ButtonPushedFcn = createCallbackFcn(app, @TrainSettingsButtonPushed, true);
            app.TrainSettings.Icon = 'settings.png';
            app.TrainSettings.Tooltip = {'Define training parameters'; ''};
            app.TrainSettings.Text = 'Training';
            app.PatchesperimageLabel = uilabel(app.Train);
            app.PatchesperimageLabel.Text = 'Patches per image';
            app.MiniBatchSizeLabel = uilabel(app.Train);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size';
            app.FiltersLabel = uilabel(app.Train);
            app.FiltersLabel.Text = 'Filters';
            app.FiltersizeLabel = uilabel(app.Train);
            app.FiltersizeLabel.Text = 'Filter size';
            app.AugmentationPanel = uipanel(app.Train);
            app.AugmentationPanel.Title = 'Augmentation';
            app.Augmentation2DSettings = uibutton(app.AugmentationPanel, 'push');
            app.Augmentation2DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation2DSettings.Icon = 'settings.png';
            app.Augmentation2DSettings.Tooltip = {'set settings for 2D augmentation'};
            app.Augmentation2DSettings.Text = '2D';
            app.Augmentation3DSettings = uibutton(app.AugmentationPanel, 'push');
            app.Augmentation3DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation3DSettings.Icon = 'settings.png';
            app.Augmentation3DSettings.Tooltip = {'set settings for 3D augmentation'; ''};
            app.Augmentation3DSettings.Text = '3D';
            app.T_AugmentationPreview = uibutton(app.AugmentationPanel, 'push');
            app.T_AugmentationPreview.ButtonPushedFcn = createCallbackFcn(app, @T_AugmentationPreviewButtonPushed, true);
            app.T_AugmentationPreview.Tooltip = {'preview random augmentations of a patch'};
            app.T_AugmentationPreview.Text = 'Preview';
            app.T_AugmentationPreviewSettings = uibutton(app.AugmentationPanel, 'push');
            app.T_AugmentationPreviewSettings.ButtonPushedFcn = createCallbackFcn(app, @T_AugmentationPreviewSettingsButtonPushed, true);
            app.T_AugmentationPreviewSettings.Icon = 'settings.png';
            app.T_AugmentationPreviewSettings.Tooltip = {'define settings for preview of the augmented patches'; ''};
            app.T_AugmentationPreviewSettings.Text = '';
            app.T_augmentation = uicheckbox(app.AugmentationPanel);
            app.T_augmentation.ValueChangedFcn = createCallbackFcn(app, @T_augmentationValueChanged, true);
            app.T_augmentation.Tooltip = {'enable data augmentation'};
            app.T_augmentation.Text = 'augmentation';
            app.PaddingLabel = uilabel(app.Train);
            app.PaddingLabel.Text = 'Padding';
            app.InputLayerSettings = uibutton(app.Train, 'push');
            app.InputLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @InputLayerSettingsButtonPushed, true);
            app.InputLayerSettings.Icon = 'settings.png';
            app.InputLayerSettings.Tooltip = {'Define training parameters'; ''};
            app.InputLayerSettings.Text = 'Input layer';
            app.TransferLearning = uibutton(app.Train, 'push');
            app.TransferLearning.ButtonPushedFcn = createCallbackFcn(app, @TransferLearningButtonPushed, true);
            app.TransferLearning.Tooltip = {'Modify the loaded network for a different number of classes'};
            app.TransferLearning.Text = 'Transfer learning';
            app.T_RandomgeneratorseedLabel = uilabel(app.Train);
            app.T_RandomgeneratorseedLabel.Text = 'Random seed';
            app.ActivationlayerLabel = uilabel(app.Train);
            app.ActivationlayerLabel.Text = 'Activation layer';
            app.T_ActivationLayerSettings = uibutton(app.Train, 'push');
            app.T_ActivationLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @T_ActivationLayerSettingsButtonPushed, true);
            app.T_ActivationLayerSettings.Icon = 'settings.png';
            app.T_ActivationLayerSettings.Tooltip = {'define settings for preview of the augmented patches'; ''};
            app.T_ActivationLayerSettings.Text = '';
            app.T_SegmentationLayerSettings = uibutton(app.Train, 'push');
            app.T_SegmentationLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @T_SegmentationLayerSettingsButtonPushed, true);
            app.T_SegmentationLayerSettings.Icon = 'settings.png';
            app.T_SegmentationLayerSettings.Tooltip = {'define settings for preview of the augmented patches'; ''};
            app.T_SegmentationLayerSettings.Text = '';
            app.T_InputPatchSize = uieditfield(app.Train, 'text');
            app.T_InputPatchSize.ValueChangedFcn = createCallbackFcn(app, @T_InputPatchSizeValueChanged, true);
            app.T_InputPatchSize.Value = '64 64 32';
            app.T_ConvolutionPadding = uidropdown(app.Train);
            app.T_ConvolutionPadding.Items = {'same', 'valid'};
            app.T_ConvolutionPadding.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ConvolutionPadding.Value = 'same';
            app.T_NumberOfClasses = uieditfield(app.Train, 'numeric');
            app.T_NumberOfClasses.ValueChangedFcn = createCallbackFcn(app, @T_NumberOfClassesValueChanged, true);
            app.T_NumberOfClasses.Value = 3;
            app.T_EncoderDepth = uieditfield(app.Train, 'numeric');
            app.T_EncoderDepth.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_EncoderDepth.Value = 3;
            app.T_PatchesPerImage = uieditfield(app.Train, 'numeric');
            app.T_PatchesPerImage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_PatchesPerImage.Value = 32;
            app.T_MiniBatchSize = uieditfield(app.Train, 'numeric');
            app.T_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_MiniBatchSize.Tooltip = {'Random seed generator for consistent training, use 0 for random initialization'};
            app.T_MiniBatchSize.Value = 4;
            app.T_NumFirstEncoderFilters = uieditfield(app.Train, 'numeric');
            app.T_NumFirstEncoderFilters.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_NumFirstEncoderFilters.Value = 32;
            app.T_FilterSize = uieditfield(app.Train, 'numeric');
            app.T_FilterSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_FilterSize.Value = 3;
            app.T_ActivationLayer = uidropdown(app.Train);
            app.T_ActivationLayer.ValueChangedFcn = createCallbackFcn(app, @T_ActivationLayerValueChanged, true);
            app.T_SegmentationLayer = uidropdown(app.Train);
            app.T_SegmentationLayer.ValueChangedFcn = createCallbackFcn(app, @T_SegmentationLayerValueChanged, true);
            app.T_RandomGeneratorSeed = uieditfield(app.Train, 'numeric');
            app.T_RandomGeneratorSeed.Limits = [0 Inf];
            app.T_RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.T_RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_RandomGeneratorSeed.Value = 2;
            app.T_SaveProgress = uicheckbox(app.Train);
            app.T_SaveProgress.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_SaveProgress.Text = 'Save progress after each epoch';
            app.T_ExportTrainingPlots = uicheckbox(app.Train);
            app.T_ExportTrainingPlots.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ExportTrainingPlots.Text = 'Export training plots';
            app.Predict = uitab(app.Mode);
            app.Predict.Title = 'Predict';
            app.PreviewresultsPanel = uipanel(app.Predict);
            app.PreviewresultsPanel.Title = 'Preview results';
            app.LoadModels = uibutton(app.PreviewresultsPanel, 'push');
            app.LoadModels.ButtonPushedFcn = createCallbackFcn(app, @LoadModelsButtonPushed, true);
            app.LoadModels.Text = 'Load images and models';
            app.LoadPredictions = uibutton(app.PreviewresultsPanel, 'push');
            app.LoadPredictions.ButtonPushedFcn = createCallbackFcn(app, @LoadPredictionsButtonPushed, true);
            app.LoadPredictions.Text = 'Load prediction scores';
            app.PreviewResultsText = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText.Text = 'Use buttons below to preview predictions.';
            app.PreviewResultsText2 = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText2.Text = 'Evaluate segmentation';
            app.EvaluateSegmentation = uibutton(app.PreviewresultsPanel, 'push');
            app.EvaluateSegmentation.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSegmentationButtonPushed, true);
            app.EvaluateSegmentation.Text = 'Evaluate segmentation';
            app.bigImageMode = uicheckbox(app.PreviewresultsPanel);
            app.bigImageMode.Tooltip = {'this is an experimantal mode implemented only for 2D "same" padding architectures'};
            app.bigImageMode.Text = 'bigimage mode';
            app.ExploreActivations = uibutton(app.Predict, 'push');
            app.ExploreActivations.ButtonPushedFcn = createCallbackFcn(app, @ExploreActivationsButtonPushed, true);
            app.ExploreActivations.Text = 'Explore activations';
            app.MinibatchsizeforpredictionnotusedintheoverlappingmodeLabel = uilabel(app.Predict);
            app.MinibatchsizeforpredictionnotusedintheoverlappingmodeLabel.Text = 'Mini batch size for prediction (not used in the overlapping mode)';
            app.P_MiniBatchSize = uieditfield(app.Predict, 'numeric');
            app.P_MiniBatchSize.Limits = [1 Inf];
            app.P_MiniBatchSize.RoundFractionalValues = 'on';
            app.P_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_MiniBatchSize.Value = 1;
            app.P_OverlappingTiles = uicheckbox(app.Predict);
            app.P_OverlappingTiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlappingTiles.Text = 'Overlapping tiles';
            app.ScorefilesDropDownLabel = uilabel(app.Predict);
            app.ScorefilesDropDownLabel.Text = 'Score files';
            app.P_ScoreFiles = uidropdown(app.Predict);
            app.P_ScoreFiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ModelfilesLabel = uilabel(app.Predict);
            app.ModelfilesLabel.Text = 'Model files';
            app.P_ModelFiles = uidropdown(app.Predict);
            app.P_ModelFiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Options = uitab(app.Mode);
            app.Options.Title = 'Options';
            app.ConfigfilesPanel = uipanel(app.Options);
            app.ConfigfilesPanel.Title = 'Config files';
            app.LoadButton = uibutton(app.ConfigfilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ConfigfilesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ConfigFilesLabel = uilabel(app.ConfigfilesPanel);
            app.CustomtrainingplotPanel = uipanel(app.Options);
            app.CustomtrainingplotPanel.Title = 'Custom training plot';
            app.O_CustomTrainingProgressWindow = uicheckbox(app.CustomtrainingplotPanel);
            app.O_CustomTrainingProgressWindow.ValueChangedFcn = createCallbackFcn(app, @O_CustomTrainingProgressWindowValueChanged, true);
            app.O_CustomTrainingProgressWindow.Text = 'Custom training progress window';
            app.RefreshrateiterLabel = uilabel(app.CustomtrainingplotPanel);
            app.RefreshrateiterLabel.Text = 'Refresh rate (iter)';
            app.NumberofpointsLabel = uilabel(app.CustomtrainingplotPanel);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.O_PreviewImagePatches = uicheckbox(app.CustomtrainingplotPanel);
            app.O_PreviewImagePatches.ValueChangedFcn = createCallbackFcn(app, @O_PreviewImagePatchesValueChanged, true);
            app.O_PreviewImagePatches.Text = 'Preview image patches';
            app.FractionofimagesforpreviewLabel = uilabel(app.CustomtrainingplotPanel);
            app.FractionofimagesforpreviewLabel.Text = 'Fraction of images for preview';
            app.O_RefreshRateIter = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_RefreshRateIter.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_RefreshRateIter.Value = 5;
            app.O_NumberOfPoints = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_NumberOfPoints.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_NumberOfPoints.Value = 1000;
            app.O_FractionOfPreviewPatches = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_FractionOfPreviewPatches.ValueDisplayFormat = '%11.5g';
            app.O_FractionOfPreviewPatches.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_FractionOfPreviewPatches.Value = 0.05;
            app.Reset2DaugmentationButton = uibutton(app.Options, 'push');
            app.Reset2DaugmentationButton.ButtonPushedFcn = createCallbackFcn(app, @Reset2DaugmentationButtonPushed, true);
            app.Reset2DaugmentationButton.Text = 'Reset 2D augmentation';
            app.Disable2DaugmentationsButton = uibutton(app.Options, 'push');
            app.Disable2DaugmentationsButton.ButtonPushedFcn = createCallbackFcn(app, @Disable2DaugmentationsButtonPushed, true);
            app.Disable2DaugmentationsButton.Tooltip = {'set all 2D augmentations to the OFF state'};
            app.Disable2DaugmentationsButton.Text = 'Disable 2D augmentations';
            app.OptionsToolsPanel = uipanel(app.Options);
            app.OptionsToolsPanel.Title = 'Tools';
            app.ExportNetworkToONNXButton = uibutton(app.OptionsToolsPanel, 'push');
            app.ExportNetworkToONNXButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNetworkToONNXButtonPushed, true);
            app.ExportNetworkToONNXButton.Tooltip = {'Export network to the ONNX Model Format'};
            app.ExportNetworkToONNXButton.Text = 'Export network to ONNX ';
            app.OptionsCountLabelsButton = uibutton(app.OptionsToolsPanel, 'push');
            app.OptionsCountLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsCountLabelsButtonPushed, true);
            app.OptionsCountLabelsButton.Tooltip = {'Count labels in model files'};
            app.OptionsCountLabelsButton.Text = 'Count labels';
            app.ImportNetworkButton = uibutton(app.OptionsToolsPanel, 'push');
            app.ImportNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ImportNetworkButtonPushed, true);
            app.ImportNetworkButton.Tooltip = {'import external network to be used with DeepMIB'};
            app.ImportNetworkButton.Text = 'Import network';
            app.NetworkPanel = uipanel(app.GridLayout);
            app.NetworkPanel.Title = 'Network';
            app.NetworkfilenameEditFieldLabel = uilabel(app.NetworkPanel);
            app.NetworkfilenameEditFieldLabel.Text = 'Network filename:';
            app.ArchitectureLabel = uilabel(app.NetworkPanel);
            app.ArchitectureLabel.Text = 'Architecture:';
            app.GPULabel_2 = uilabel(app.NetworkPanel);
            app.GPULabel_2.Text = 'GPU:';
            app.NetworkFilename = uieditfield(app.NetworkPanel, 'text');
            app.NetworkFilename.ValueChangedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.NetworkFilename.Enable = 'off';
            app.SelectNetwork = uibutton(app.NetworkPanel, 'push');
            app.SelectNetwork.ButtonPushedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.SelectNetwork.Enable = 'off';
            app.SelectNetwork.Text = '...';
            app.gpuInfoBtn = uibutton(app.NetworkPanel, 'push');
            app.gpuInfoBtn.ButtonPushedFcn = createCallbackFcn(app, @gpuInfoBtnButtonPushed, true);
            app.gpuInfoBtn.Tooltip = {'GPU info: display information about the selected GPU device'};
            app.gpuInfoBtn.Text = '?';
            app.GPUDropDown = uidropdown(app.NetworkPanel);
            app.GPUDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUDropDownValueChanged, true);
            app.GPUDropDown.Tooltip = {'select a GPU device to be used for training or prediction'};
            app.Architecture = uidropdown(app.NetworkPanel);
            app.Architecture.Items = {'2D U-net', '3D U-net', ''};
            app.Architecture.ValueChangedFcn = createCallbackFcn(app, @ArchitectureValueChanged, true);
            app.Architecture.Value = '2D U-net';
            app.BottomButtonsPanel = uipanel(app.GridLayout);
            app.TrainButton = uibutton(app.BottomButtonsPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.Help = uibutton(app.BottomButtonsPanel, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.PreprocessButton = uibutton(app.BottomButtonsPanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.PredictButton = uibutton(app.BottomButtonsPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibDeepGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end